classdef app_NVH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PosizioneTargetGauge            matlab.ui.control.LinearGauge
        PosizioneTargetGaugeLabel       matlab.ui.control.Label
        PosizioneInizialeEditField      matlab.ui.control.NumericEditField
        PosizioneInizialeEditFieldLabel  matlab.ui.control.Label
        PosizioneAttualeGauge           matlab.ui.control.LinearGauge
        PosizioneAttualeGaugeLabel      matlab.ui.control.Label
        PosizionamentoButton            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SineTab                         matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        SelezionetipodiprovaButtonGroup  matlab.ui.container.ButtonGroup
        FrequenzaeVelocitButton_3       matlab.ui.control.RadioButton
        VelociteAmpiezzaButton          matlab.ui.control.RadioButton
        FrequenzaeAmpiezzaButton        matlab.ui.control.RadioButton
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        TestStopButton_5                matlab.ui.control.Button
        TestStartButton_5               matlab.ui.control.Button
        TriangularTab                   matlab.ui.container.Tab
        EditField_9                     matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        TestStopButton_6                matlab.ui.control.Button
        TestStartButton_6               matlab.ui.control.Button
        SelezionetipodiprovaButtonGroup_2  matlab.ui.container.ButtonGroup
        FrequenzaeVelocitButton_2       matlab.ui.control.RadioButton
        VelociteAmpiezzaButton_2        matlab.ui.control.RadioButton
        FrequenzaeAmpiezzaButton_2      matlab.ui.control.RadioButton
        SweepTab                        matlab.ui.container.Tab
        TestStopButton_7                matlab.ui.control.Button
        TestStartButton_7               matlab.ui.control.Button
        AmpiezzaInizialeEditField       matlab.ui.control.NumericEditField
        AmpiezzaInizialeEditFieldLabel  matlab.ui.control.Label
        FrequenzaFinaleEditField        matlab.ui.control.NumericEditField
        FrequenzaFinaleEditFieldLabel   matlab.ui.control.Label
        FrequenzaInizialeEditField      matlab.ui.control.NumericEditField
        FrequenzaInizialeEditFieldLabel  matlab.ui.control.Label
        RandomTab                       matlab.ui.container.Tab
        SmorzamentoNsmEditField         matlab.ui.control.NumericEditField
        SmorzamentoNsmEditFieldLabel    matlab.ui.control.Label
        TestStopButton_8                matlab.ui.control.Button
        TestStartButton_8               matlab.ui.control.Button
        MassaQuartoVeicolokgEditField   matlab.ui.control.NumericEditField
        MassaQuartoVeicolokgEditFieldLabel  matlab.ui.control.Label
        MassaPneumaticokgEditField      matlab.ui.control.NumericEditField
        MassaPneumaticokgEditFieldLabel  matlab.ui.control.Label
        VelocitVeicolokmhEditField      matlab.ui.control.NumericEditField
        VelocitVeicolokmhEditFieldLabel  matlab.ui.control.Label
        SelezioneStradaButtonGroup      matlab.ui.container.ButtonGroup
        ISODButton                      matlab.ui.control.RadioButton
        ISOCButton                      matlab.ui.control.RadioButton
        ISOBButton                      matlab.ui.control.RadioButton
        ISOAButton                      matlab.ui.control.RadioButton
        RigidezzaSospensioneNmmEditField  matlab.ui.control.NumericEditField
        RigidezzaSospensioneNmmEditFieldLabel  matlab.ui.control.Label
        RigidezzaRuotaNmmEditField      matlab.ui.control.NumericEditField
        RigidezzaRuotaNmmEditFieldLabel  matlab.ui.control.Label
        TimeHistoryTab                  matlab.ui.container.Tab
        TestStopButton_9                matlab.ui.control.Button
        TestStartButton_9               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        Lamp                            matlab.ui.control.Lamp
        PowerSwitch                     matlab.ui.control.Switch
        PowerSwitchLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        TIME_STEP = 0.05 % fixed time step
        done
        stop
        wv_type
        pow_en
        pos_en
        test_start
        test_stop
        test_sel
        Gr_sel
        pos_init_mm
        amplitude_mm
        vcar_kmh
        freq_Hz
        rate_Hz_s
        num1
        num3
        den2
        den3
        den4
        den5
        t % timer
        state
        spp
        freq_Hz1
        amplitude_mm1
        freq_Hz2
        amplitude_mm2
        fend_Hz
        aend_mm
        time;
        pos_ref;
        pos_meas
        st;
    end

    methods (Access = private)


        function Messagecreate(~,wv_type,pow_en,pos_en,test_start,test_stop,test_sel,Gr_sel,pos_init_mm,amplitude_mm,vcar_kmh,freq_Hz,rate_Hz_s,num1,num3,den2,den3,den4,den5,fend_Hz,~,sp)

            %% Message construction
            msghead = [dec2bin(wv_type),dec2bin(pow_en),dec2bin(pos_en),dec2bin(test_start),...
                dec2bin(test_stop),dec2bin(test_sel,2),dec2bin(Gr_sel,2)];

            aend_mm=(fend_Hz/freq_Hz)*pos_init_mm;

            msg = [single(bin2dec(msghead)),...
                single(pos_init_mm),...
                single(amplitude_mm),...
                single(vcar_kmh),...
                single(freq_Hz),...
                single(rate_Hz_s),...
                single(num1*1000),...
                single(num3*1000),...
                single(den2),...
                single(den3),...
                single(den4),...
                single(den5),...
                single(fend_Hz),...
                single(aend_mm),...
                single(0),...
                ];


            %% TX
            write(sp,msg,'single')
        end

        function Messagereceive(app,sp,UIAxes)
            %% RX (exit with CTRL-C)
            Nd = 30*1001; % Single frame of 0.5 seconds

            try
                rawdata = read(sp,Nd,'uint16');
                pos = strfind(rawdata,[17733,21331]);
                for idx = length(pos):-1:1
                    rawdata(pos(idx):pos(idx)+1) = [];
                end
                istart = (pos(1)-1)-floor((pos(1)-1)/3)*3+1;
                rawdata = rawdata(istart:end);
                iend = floor(length(rawdata)/3)*3;
                rawdata = rawdata(1:iend);
                data = double(reinterpretcast(uint16(rawdata),numerictype(1,16,15-ceil(log2(45)))));
                data = reshape(data',3,[])';
                app.pos_ref = [app.pos_ref(length(data)+1:end);data(:,1)];
                app.pos_meas = [app.pos_meas(length(data)+1:end);data(:,2)];
                app.st = [app.st(length(data)+1:end);data(:,3)];

                % Lamp & gauges
                app.PosizioneTargetGauge.Value=data(end,1);
                app.PosizioneAttualeGauge.Value=data(end,2);
                status=data(end,3);
                switch status
                    case -2
                        app.Lamp.Color='red';
                    case -1
                        app.Lamp.Color='red';
                    case 0
                        app.Lamp.Color=[0.9290 0.6940 0.1250];
                    case 1
                        app.Lamp.Color='cyan';
                    case 2
                        app.Lamp.Color='green';
                    case 3
                        app.Lamp.Color='green';
                    case 4
                        app.Lamp.Color='green';
                    case 5
                        app.Lamp.Color='green';
                end
                
                
                % Plot
                plot(UIAxes,app.time,app.pos_ref)
                hold(UIAxes,'on')
                plot(UIAxes,app.time,app.pos_meas)
                hold(UIAxes,'off')
                legend(UIAxes,'ref','meas','location','east')
                xlabel(UIAxes,'time [s]')
                ylabel(UIAxes,'position [mm]')
                xlim(UIAxes,[0,10])
                drawnow(UIAxes)
            catch
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.state=-1;
            fpwm = 20e3;
            Tpwm = 1/fpwm;
            Ts = Tpwm;
            Tref = 100*Ts;
            title(app.UIAxes,'RealTimePlot')
            xlabel(app.UIAxes,'time [s]')
            ylabel(app.UIAxes,'position [mm]')

            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.timerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';


            %% Profile treatment
            data = readtable('QV-500hz-mm.xlsx');
            t2 = data.Var1;
            q = data.Var2;
            plot(app.UIAxes2, t2, q);
            xlabel(app.UIAxes2,'Tempo [s]');
            ylabel(app.UIAxes2,'Posizione [mm]');
            title(app.UIAxes2,'Renault Time History');

            %% Quarter car model
            ms = 400;
            mu = 40;
            ks = 200e3;
            ku = 20e3;
            cs = 5000;
            s = tf('s');
            Gs = -s^2*(ku*ms)/(s^4*ms*mu+s^3*cs*(ms+mu)+s^2*(ku*ms+ks*(ms+mu))+s*ku*cs+ks*ku);
            Gz = c2d(Gs,Tref,'tustin');
            [numz,denz] = tfdata(Gz,'v');
            app.num1=numz(1);
            app.num3=numz(3);
            app.den2=denz(2);
            app.den3=denz(3);
            app.den4=denz(4);
            app.den5=denz(5);

            % Serial setup
            sp = serialport('COM9',12e6);
            sp.DataBits = 8;
            sp.Parity = 'none';
            sp.StopBits = 1;
            sp.ByteOrder = 'little-endian';
            sp.FlowControl = 'none';
            sp.Timeout = 2;
            fopen(sp);
            app.spp=sp;


            %Variabili interne
            app.Label_4.Text = 'Frequenza [Hz]';
            app.Label_5.Text = 'Ampiezza [mm]';
            app.Label_6.Text = 'Frequenza [Hz]';
            app.Label_7.Text = 'Ampiezza [mm]';

            %% Message parameters
            app.wv_type=0;              % Wave type, 0 sinusoidal 1 triangular
            app.pow_en = 0;             % Enable power (0/1)
            app.pos_en = 0;             % Enable position control (0/1)
            app.test_start = 0;         % Start test (0/1)
            app.test_stop = 0;          % Stop test (0/1)
            app.test_sel = 3;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault)
            app.Gr_sel = 0;             % Select road profile (0 A; 1 B; 2 C; 3 D)
            app.pos_init_mm = 0;        % Initial position (-20/20 mm)
            app.amplitude_mm = 20;      % Waveform amplitude (0/20 mm)
            app.vcar_kmh = 35;          % Vehicle speed (10/200 km/h)
            app.freq_Hz = 1;            % Waveform frequency (0.1/100 Hz)
            app.rate_Hz_s = 1;          % Sweep rate (0.1/10 Hz/s)
            app.fend_Hz=10;
            %app.aend_mm=10;
            app.amplitude_mm1 = 0;
            app.freq_Hz1 = 0;
            app.amplitude_mm2 = 0;
            app.freq_Hz2 = 0;
            app.Lamp.Color='r';

            %% Plot vectors
            Ntot = 10*20e3;
            app.time = (0:Ntot-1)/20e3;
            app.pos_ref = zeros([Ntot,1]);
            app.pos_meas = zeros([Ntot,1]);
            app.st = zeros([Ntot,1]);

        end

        % Selection changed function: 
        % SelezionetipodiprovaButtonGroup
        function SelezionetipodiprovaButtonGroupSelectionChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.Label_4.Text = 'Frequenza [Hz]';
                    app.Label_5.Text = 'Ampiezza [mm]';
                    app.EditField_6.Value=app.freq_Hz1;
                    app.EditField_7.Value=app.amplitude_mm1;
                case 'Velocità e Ampiezza'
                    app.Label_4.Text = 'Velocità [m/s]';
                    app.Label_5.Text = 'Ampiezza [mm]';
                    app.EditField_7.Value=app.amplitude_mm1;
                    app.EditField_6.Value=app.freq_Hz1*(2*pi*app.amplitude_mm1)/1000;
                case 'Frequenza e Velocità'
                    app.Label_4.Text = 'Frequenza [Hz]';
                    app.Label_5.Text = 'Velocità [m/s]';
                    app.EditField_6.Value=app.freq_Hz1;
                    app.EditField_7.Value=app.amplitude_mm1*(2*pi*app.freq_Hz1)/1000;
            end
        end

        % Selection changed function: 
        % SelezionetipodiprovaButtonGroup_2
        function SelezionetipodiprovaButtonGroup_2SelectionChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup_2.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.Label_6.Text = 'Frequenza [Hz]';
                    app.Label_7.Text = 'Ampiezza [mm]';
                    app.EditField_8.Value=app.freq_Hz2;
                    app.EditField_9.Value=app.amplitude_mm2;
                case 'Velocità e Ampiezza'
                    app.Label_6.Text = 'Velocità [m/s]';
                    app.Label_7.Text = 'Ampiezza [mm]';
                    app.EditField_9.Value=app.amplitude_mm2;
                    app.EditField_8.Value=app.freq_Hz2*(2*pi*app.amplitude_mm2)/1000;
                case 'Frequenza e Velocità'
                    app.Label_6.Text = 'Frequenza [Hz]';
                    app.Label_7.Text = 'Velocità [m/s]';
                    app.EditField_8.Value=app.freq_Hz2;
                    app.EditField_9.Value=app.amplitude_mm2*(2*pi*app.freq_Hz2)/1000;
            end
        end

        % Button pushed function: TestStartButton_5
        function TestStartButton_5Pushed(app, event)

            app.wv_type=0;              % Wave type, 0 sinusoidal 1 triangular

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.Label_4.Text = 'Frequenza [Hz]';
                    app.Label_5.Text = 'Ampiezza [mm]';
                    app.freq_Hz=app.EditField_6.Value;
                    app.amplitude_mm=app.EditField_7.Value;
                case 'Velocità e Ampiezza'
                    app.Label_4.Text = 'Velocità [m/s]';
                    app.Label_5.Text = 'Ampiezza [mm]';
                    app.amplitude_mm=app.EditField_7.Value;
                    app.freq_Hz=app.EditField_6.Value/(2*pi*app.amplitude_mm*1000);
                case 'Frequenza e Velocità'
                    app.Label_4.Text = 'Frequenza [Hz]';
                    app.Label_5.Text = 'Velocità [m/s]';
                    app.freq_Hz=app.EditField_6.Value;
                    app.amplitude_mm=app.EditField_7.Value/(2*pi*app.freq_Hz*1000);
            end

            if strcmp(app.PowerSwitch.Value,'On')
                app.done=false;
                app.stop=false;
                app.test_sel = 0;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault)
                app.test_start=1;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                while ~app.stop&&strcmp(app.PowerSwitch.Value,'On')
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                    app.stop=app.done;
                end
            end

        end

        % Button pushed function: TestStartButton_7
        function TestStartButton_7Pushed(app, event)

            app.amplitude_mm=app.AmpiezzaInizialeEditField.Value;
            app.freq_Hz=app.FrequenzaInizialeEditField.Value;
            app.fend_Hz=app.FrequenzaFinaleEditField.Value;
            if strcmp(app.PowerSwitch.Value,'On')
                app.wv_type=0;
                app.done=false;
                app.stop=false;
                app.test_sel = 1;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault)
                app.test_start=1;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                while ~app.stop&&strcmp(app.PowerSwitch.Value,'On')
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                    app.stop=app.done;
                end
            end
        end

        % Button pushed function: TestStartButton_6
        function TestStartButton_6Pushed(app, event)

            app.wv_type=1;              % Wave type, 0 sinusoidal 1 triangular

            switch app.SelezionetipodiprovaButtonGroup_2.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.Label_6.Text = 'Frequenza [Hz]';
                    app.Label_7.Text = 'Ampiezza [mm]';
                    app.freq_Hz=app.EditField_8.Value;
                    app.amplitude_mm=app.EditField_9.Value;
                case 'Velocità e Ampiezza'
                    app.Label_6.Text = 'Velocità [m/s]';
                    app.Label_7.Text = 'Ampiezza [mm]';
                    app.amplitude_mm=app.EditField_9.Value;
                    app.freq_Hz=app.EditField_8.Value/(2*pi*app.amplitude_mm);
                case 'Frequenza e Velocità'
                    app.Label_6.Text = 'Frequenza [Hz]';
                    app.Label_7.Text = 'Velocità [m/s]';
                    app.freq_Hz=app.EditField_8.Value;
                    app.amplitude_mm=app.EditField_9.Value/(2*pi*app.freq_Hz);
            end

            if strcmp(app.PowerSwitch.Value,'On')
                app.done=false;
                app.stop=false;
                app.test_sel = 0;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault) aggiungere triangular
                app.test_start=1;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                while ~app.stop&&strcmp(app.PowerSwitch.Value,'On')
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                    app.stop=app.done;
                end
            end
        end

        % Button pushed function: TestStartButton_8
        function TestStartButton_8Pushed(app, event)

            if strcmp(app.PowerSwitch.Value,'On')
                app.done=false;
                app.stop=false;
                app.test_sel = 2;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault)
                app.test_start=1;

                %% Quarter car model
                ms = app.MassaQuartoVeicolokgEditField.Value;
                mu = app.MassaPneumaticokgEditField.Value;
                ks = app.RigidezzaSospensioneNmmEditField.Value*1000;
                ku = app.RigidezzaRuotaNmmEditField.Value*1000;
                cs = app.SmorzamentoNsmEditField.Value;
                app.vcar_kmh=app.VelocitVeicolokmhEditField.Value;

                fpwm = 20e3;
                Tpwm = 1/fpwm;
                Ts = Tpwm;
                Tref = 100*Ts;
                s = tf('s');

                switch app.SelezioneStradaButtonGroup.SelectedObject.Text
                    case 'ISO A'
                        app.Gr_sel=0;
                    case 'ISO B'
                        app.Gr_sel=1;
                    case 'ISO C'
                        app.Gr_sel=2;
                    case 'ISO D'
                        app.Gr_sel=3;
                end
                Gs = -s^2*(ku*ms)/(s^4*ms*mu+s^3*cs*(ms+mu)+s^2*(ku*ms+ks*(ms+mu))+s*ku*cs+ks*ku);
                Gz = c2d(Gs,Tref,'tustin');
                [numz,denz] = tfdata(Gz,'v');
                app.num1=numz(1);
                app.num3=numz(3);
                app.den2=denz(2);
                app.den3=denz(3);
                app.den4=denz(4);
                app.den5=denz(5);

                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);

                while ~app.stop&&strcmp(app.PowerSwitch.Value,'On')
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                    app.stop=app.done;
                end

            end
        end

        % Button pushed function: TestStartButton_9
        function TestStartButton_9Pushed(app, event)
            if strcmp(app.PowerSwitch.Value,'On')
                app.done=false;
                app.stop=false;
                app.test_sel = 3;           % Select test (0 Sine; 1 Sweep; 2 QC; 3 Renault)
                app.test_start=1;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                while ~app.stop&&strcmp(app.PowerSwitch.Value,'On')
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                    app.stop=app.done;
                end
            end
        end

        % Button pushed function: PosizionamentoButton
        function PosizionamentoButtonPushed(app, event)

            if strcmp(app.PowerSwitch.Value,'On')
                app.pos_init_mm=app.PosizioneInizialeEditField.Value/1000;
                app.pos_en=1;
                app.state=0;

                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);

                while app.pos_en==1
                    Messagereceive(app,app.spp,app.UIAxes);
                    pause(0.001);
                end

            end

            if strcmp(app.PowerSwitch.Value,'Off')
                app.pos_en=0;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                N = 0;
                while N<10
                    Messagereceive(app,app.spp,app.UIAxes);
                    N=N+1;
                    pause(0.001);
                end

            end

        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)

            if strcmp(app.PowerSwitch.Value,'On')
                app.pow_en=1;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                N = 0;
                while N<10
                    Messagereceive(app,app.spp,app.UIAxes);
                    N=N+1;
                    pause(0.001);
                end

            end

            if strcmp(app.PowerSwitch.Value,'Off')
                app.pow_en=0;
                Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
                N = 0;
                while N<10
                    Messagereceive(app,app.spp,app.UIAxes);
                    N=N+1;
                    pause(0.001);
                end

            end
            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.EditField_6.Value=app.freq_Hz1;
                    app.EditField_7.Value=app.amplitude_mm1;
                case 'Velocità e Ampiezza'
                    app.EditField_7.Value=app.amplitude_mm1;
                    app.EditField_6.Value=app.freq_Hz1*(2*pi*app.amplitude_mm1);
                case 'Frequenza e Velocità'
                    app.EditField_6.Value=app.freq_Hz1;
                    app.EditField_7.Value=app.amplitude_mm1*(2*pi*app.freq_Hz1);
            end

            switch app.SelezionetipodiprovaButtonGroup_2.SelectedObject.Text
                case 'Frequenza e Ampiezza'
                    app.EditField_8.Value=app.freq_Hz2;
                    app.EditField_9.Value=app.amplitude_mm2;
                case 'Velocità e Ampiezza'
                    app.EditField_9.Value=app.amplitude_mm2;
                    app.EditField_8.Value=app.freq_Hz2*(2*pi*app.amplitude_mm2);
                case 'Frequenza e Velocità'
                    app.EditField_8.Value=app.freq_Hz2;
                    app.EditField_9.Value=app.amplitude_mm2*(2*pi*app.freq_Hz2);
            end
        end

        % Button pushed function: TestStopButton_5, 
        % TestStopButton_6, TestStopButton_7, TestStopButton_8, 
        % TestStopButton_9
        function TestStopButton_5Pushed(app, event)
            app.done=true;
            app.test_stop=1;
            app.test_start=0;

            Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
            N = 0;
            while N<10
                Messagereceive(app,app.spp,app.UIAxes);
                N=N+1;
                pause(0.001);
            end
            app.test_stop = 0;          % Stop test (0/1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.test_stop=1;
            app.test_start=0;
            Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
            delete(app)
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            app.test_stop=1;
            app.test_start=0;
            Messagecreate(app,app.wv_type,app.pow_en,app.pos_en,app.test_start,app.test_stop,app.test_sel,app.Gr_sel,app.pos_init_mm,app.amplitude_mm,app.vcar_kmh,app.freq_Hz,app.rate_Hz_s,app.num1,app.num3,app.den2,app.den3,app.den4,app.den5,app.fend_Hz,app.aend_mm,app.spp);
            delete(app)
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text
                case 'Frequenza e Ampiezza'
                    app.freq_Hz1=app.EditField_6.Value;
                case 'Velocità e Ampiezza'
                    app.freq_Hz1=app.EditField_6.Value/(2*pi*app.EditField_7.Value)*1000;
                case 'Frequenza e Velocità'
                    app.freq_Hz1=app.EditField_6.Value;
            end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text

                case 'Frequenza e Ampiezza'
                    app.amplitude_mm1=app.EditField_7.Value;
                case 'Velocità e Ampiezza'
                    app.amplitude_mm1=app.EditField_7.Value;
                case 'Frequenza e Velocità'
                    app.amplitude_mm1=app.EditField_7.Value/(2*pi*app.EditField_6.Value)*1000;
            end
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text
                case 'Frequenza e Ampiezza'
                    app.freq_Hz2=app.EditField_8.Value;
                case 'Velocità e Ampiezza'
                    app.freq_Hz2=app.EditField_8.Value/(2*pi*app.EditField_9.Value)*1000;
                case 'Frequenza e Velocità'
                    app.freq_Hz2=app.EditField_8.Value;
            end
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)

            switch app.SelezionetipodiprovaButtonGroup.SelectedObject.Text
                case 'Frequenza e Ampiezza'
                    app.amplitude_mm2=app.EditField_9.Value;
                case 'Velocità e Ampiezza'
                    app.amplitude_mm2=app.EditField_9.Value;
                case 'Frequenza e Velocità'
                    app.amplitude_mm2=app.EditField_9.Value/(2*pi*app.EditField_8.Value)*1000;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.TestStartButton_5 = uibutton(app.SineTab, 'push');
            app.TestStartButton_5.ButtonPushedFcn = createCallbackFcn(app, @TestStartButton_5Pushed, true);
            app.TestStartButton_5.Text = 'Test Start';
            app.TestStopButton_5 = uibutton(app.SineTab, 'push');
            app.TestStopButton_5.ButtonPushedFcn = createCallbackFcn(app, @TestStopButton_5Pushed, true);
            app.TestStopButton_5.Text = 'Test Stop';
            app.EditField_6 = uieditfield(app.SineTab, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.SineTab, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.SelezionetipodiprovaButtonGroup = uibuttongroup(app.SineTab);
            app.SelezionetipodiprovaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelezionetipodiprovaButtonGroupSelectionChanged, true);
            app.SelezionetipodiprovaButtonGroup.Title = 'Selezione tipo di prova';
            app.FrequenzaeAmpiezzaButton = uiradiobutton(app.SelezionetipodiprovaButtonGroup);
            app.FrequenzaeAmpiezzaButton.Text = 'Frequenza e Ampiezza';
            app.FrequenzaeAmpiezzaButton.Value = true;
            app.VelociteAmpiezzaButton = uiradiobutton(app.SelezionetipodiprovaButtonGroup);
            app.VelociteAmpiezzaButton.Text = 'Velocità e Ampiezza';
            app.FrequenzaeVelocitButton_3 = uiradiobutton(app.SelezionetipodiprovaButtonGroup);
            app.FrequenzaeVelocitButton_3.Text = 'Frequenza e Velocità';
            app.Label_4 = uilabel(app.SineTab);
            app.Label_5 = uilabel(app.SineTab);
            app.TriangularTab = uitab(app.TabGroup);
            app.TriangularTab.Title = 'Triangular';
            app.SelezionetipodiprovaButtonGroup_2 = uibuttongroup(app.TriangularTab);
            app.SelezionetipodiprovaButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SelezionetipodiprovaButtonGroup_2SelectionChanged, true);
            app.SelezionetipodiprovaButtonGroup_2.Title = 'Selezione tipo di prova';
            app.FrequenzaeAmpiezzaButton_2 = uiradiobutton(app.SelezionetipodiprovaButtonGroup_2);
            app.FrequenzaeAmpiezzaButton_2.Text = 'Frequenza e Ampiezza';
            app.FrequenzaeAmpiezzaButton_2.Value = true;
            app.VelociteAmpiezzaButton_2 = uiradiobutton(app.SelezionetipodiprovaButtonGroup_2);
            app.VelociteAmpiezzaButton_2.Text = 'Velocità e Ampiezza';
            app.FrequenzaeVelocitButton_2 = uiradiobutton(app.SelezionetipodiprovaButtonGroup_2);
            app.FrequenzaeVelocitButton_2.Text = 'Frequenza e Velocità';
            app.TestStartButton_6 = uibutton(app.TriangularTab, 'push');
            app.TestStartButton_6.ButtonPushedFcn = createCallbackFcn(app, @TestStartButton_6Pushed, true);
            app.TestStartButton_6.Text = 'Test Start';
            app.TestStopButton_6 = uibutton(app.TriangularTab, 'push');
            app.TestStopButton_6.ButtonPushedFcn = createCallbackFcn(app, @TestStopButton_5Pushed, true);
            app.TestStopButton_6.Text = 'Test Stop';
            app.Label_6 = uilabel(app.TriangularTab);
            app.EditField_8 = uieditfield(app.TriangularTab, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.Label_7 = uilabel(app.TriangularTab);
            app.EditField_9 = uieditfield(app.TriangularTab, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.SweepTab = uitab(app.TabGroup);
            app.SweepTab.Title = 'Sweep';
            app.FrequenzaInizialeEditFieldLabel = uilabel(app.SweepTab);
            app.FrequenzaInizialeEditFieldLabel.Text = 'Frequenza Iniziale';
            app.FrequenzaInizialeEditField = uieditfield(app.SweepTab, 'numeric');
            app.FrequenzaFinaleEditFieldLabel = uilabel(app.SweepTab);
            app.FrequenzaFinaleEditFieldLabel.Text = 'Frequenza Finale';
            app.FrequenzaFinaleEditField = uieditfield(app.SweepTab, 'numeric');
            app.AmpiezzaInizialeEditFieldLabel = uilabel(app.SweepTab);
            app.AmpiezzaInizialeEditFieldLabel.Text = 'Ampiezza Iniziale';
            app.AmpiezzaInizialeEditField = uieditfield(app.SweepTab, 'numeric');
            app.TestStartButton_7 = uibutton(app.SweepTab, 'push');
            app.TestStartButton_7.ButtonPushedFcn = createCallbackFcn(app, @TestStartButton_7Pushed, true);
            app.TestStartButton_7.Text = 'Test Start';
            app.TestStopButton_7 = uibutton(app.SweepTab, 'push');
            app.TestStopButton_7.ButtonPushedFcn = createCallbackFcn(app, @TestStopButton_5Pushed, true);
            app.TestStopButton_7.Text = 'Test Stop';
            app.RandomTab = uitab(app.TabGroup);
            app.RandomTab.Title = 'Random';
            app.RigidezzaRuotaNmmEditFieldLabel = uilabel(app.RandomTab);
            app.RigidezzaRuotaNmmEditFieldLabel.Text = 'Rigidezza Ruota [N/mm]';
            app.RigidezzaRuotaNmmEditField = uieditfield(app.RandomTab, 'numeric');
            app.RigidezzaRuotaNmmEditField.Value = 200;
            app.RigidezzaSospensioneNmmEditFieldLabel = uilabel(app.RandomTab);
            app.RigidezzaSospensioneNmmEditFieldLabel.Text = 'Rigidezza Sospensione [N/mm]';
            app.RigidezzaSospensioneNmmEditField = uieditfield(app.RandomTab, 'numeric');
            app.RigidezzaSospensioneNmmEditField.Value = 20;
            app.SelezioneStradaButtonGroup = uibuttongroup(app.RandomTab);
            app.SelezioneStradaButtonGroup.Title = 'Selezione Strada';
            app.ISOAButton = uiradiobutton(app.SelezioneStradaButtonGroup);
            app.ISOAButton.Text = 'ISO A';
            app.ISOAButton.Value = true;
            app.ISOBButton = uiradiobutton(app.SelezioneStradaButtonGroup);
            app.ISOBButton.Text = 'ISO B';
            app.ISOCButton = uiradiobutton(app.SelezioneStradaButtonGroup);
            app.ISOCButton.Text = 'ISO C';
            app.ISODButton = uiradiobutton(app.SelezioneStradaButtonGroup);
            app.ISODButton.Text = 'ISO D';
            app.VelocitVeicolokmhEditFieldLabel = uilabel(app.RandomTab);
            app.VelocitVeicolokmhEditFieldLabel.Text = 'Velocità Veicolo [km/h]';
            app.VelocitVeicolokmhEditField = uieditfield(app.RandomTab, 'numeric');
            app.VelocitVeicolokmhEditField.Value = 50;
            app.MassaPneumaticokgEditFieldLabel = uilabel(app.RandomTab);
            app.MassaPneumaticokgEditFieldLabel.Text = 'Massa Pneumatico [kg]';
            app.MassaPneumaticokgEditField = uieditfield(app.RandomTab, 'numeric');
            app.MassaPneumaticokgEditField.Value = 30;
            app.MassaQuartoVeicolokgEditFieldLabel = uilabel(app.RandomTab);
            app.MassaQuartoVeicolokgEditFieldLabel.Text = 'Massa Quarto Veicolo [kg]';
            app.MassaQuartoVeicolokgEditField = uieditfield(app.RandomTab, 'numeric');
            app.MassaQuartoVeicolokgEditField.Value = 400;
            app.TestStartButton_8 = uibutton(app.RandomTab, 'push');
            app.TestStartButton_8.ButtonPushedFcn = createCallbackFcn(app, @TestStartButton_8Pushed, true);
            app.TestStartButton_8.Text = 'Test Start';
            app.TestStopButton_8 = uibutton(app.RandomTab, 'push');
            app.TestStopButton_8.ButtonPushedFcn = createCallbackFcn(app, @TestStopButton_5Pushed, true);
            app.TestStopButton_8.Text = 'Test Stop';
            app.SmorzamentoNsmEditFieldLabel = uilabel(app.RandomTab);
            app.SmorzamentoNsmEditFieldLabel.Text = 'Smorzamento [Ns/m]';
            app.SmorzamentoNsmEditField = uieditfield(app.RandomTab, 'numeric');
            app.SmorzamentoNsmEditField.Value = 5000;
            app.TimeHistoryTab = uitab(app.TabGroup);
            app.TimeHistoryTab.Title = 'Time History';
            app.UIAxes2 = uiaxes(app.TimeHistoryTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.TimeHistoryTab);
            app.Label.Text = 'Questa finestra fa partire la prova di rumorosità secondo standard Renault';
            app.TestStartButton_9 = uibutton(app.TimeHistoryTab, 'push');
            app.TestStartButton_9.ButtonPushedFcn = createCallbackFcn(app, @TestStartButton_9Pushed, true);
            app.TestStartButton_9.Text = 'Test Start';
            app.TestStopButton_9 = uibutton(app.TimeHistoryTab, 'push');
            app.TestStopButton_9.ButtonPushedFcn = createCallbackFcn(app, @TestStopButton_5Pushed, true);
            app.TestStopButton_9.Text = 'Test Stop';
            app.PosizionamentoButton = uibutton(app.UIFigure, 'push');
            app.PosizionamentoButton.ButtonPushedFcn = createCallbackFcn(app, @PosizionamentoButtonPushed, true);
            app.PosizionamentoButton.Text = 'Posizionamento';
            app.PosizioneAttualeGaugeLabel = uilabel(app.UIFigure);
            app.PosizioneAttualeGaugeLabel.Text = 'Posizione Attuale';
            app.PosizioneAttualeGauge = uigauge(app.UIFigure, 'linear');
            app.PosizioneAttualeGauge.Limits = [-25 25];
            app.PosizioneAttualeGauge.MajorTicks = [-25 -20 -15 -10 -5 0 5 10 15 20 25];
            app.PosizioneInizialeEditFieldLabel = uilabel(app.UIFigure);
            app.PosizioneInizialeEditFieldLabel.Text = 'Posizione Iniziale';
            app.PosizioneInizialeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosizioneTargetGaugeLabel = uilabel(app.UIFigure);
            app.PosizioneTargetGaugeLabel.Text = 'Posizione Target';
            app.PosizioneTargetGauge = uigauge(app.UIFigure, 'linear');
            app.PosizioneTargetGauge.Limits = [-25 25];
            app.PosizioneTargetGauge.MajorTicks = [-25 -20 -15 -10 -5 0 5 10 15 20 25];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_NVH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end